classdef TestBinaryThreshold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TextArea                    matlab.ui.control.TextArea
        GetImageDirectoryButton     matlab.ui.control.Button
        BinaryThresholdSlider       matlab.ui.control.Slider
        BinaryThresholdSliderLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img % Image to be used to check binary thresholding
        imgBitDepth %Bit depth of image given from directory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: BinaryThresholdSlider
        function BinaryThresholdSliderValueChanged(app, event)
            value = app.BinaryThresholdSlider.Value;
            app.TextArea.Value = num2str(value);
            

            imshow(app.img>value*2^app.imgBitDepth,'parent',app.UIAxes);
        end

        % Button pushed function: GetImageDirectoryButton
        function GetImageDirectoryButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            app.img = imread(fullfile(path,file));
            imgInfo = imfinfo(fullfile(path,file));
            app.imgBitDepth = imgInfo.BitDepth;

            imshow(app.img,'parent',app.UIAxes)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.BinaryThresholdSliderLabel = uilabel(app.UIFigure);
            app.BinaryThresholdSliderLabel.Text = 'Binary Threshold';
            app.BinaryThresholdSlider = uislider(app.UIFigure);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.GetImageDirectoryButton = uibutton(app.UIFigure, 'push');
            app.GetImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageDirectoryButtonPushed, true);
            app.GetImageDirectoryButton.Text = 'Get Image Directory';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestBinaryThreshold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end