classdef GUIPIVCode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadimagesTab                   matlab.ui.container.Tab
        LoadImagesLabel                 matlab.ui.control.Label
        ImageNumberSpinner_2            matlab.ui.control.Spinner
        ImageNumberSpinner_2Label       matlab.ui.control.Label
        RotateEditField                 matlab.ui.control.EditField
        RotateEditFieldLabel            matlab.ui.control.Label
        BitShiftEditField               matlab.ui.control.EditField
        BitShiftEditFieldLabel          matlab.ui.control.Label
        ExtensionEditField              matlab.ui.control.EditField
        ExtensionEditFieldLabel         matlab.ui.control.Label
        ImgRangeLow                     matlab.ui.control.NumericEditField
        ImageRangeEditFieldLabel        matlab.ui.control.Label
        FolderEditField                 matlab.ui.control.EditField
        FolderEditFieldLabel            matlab.ui.control.Label
        UsuallyonlyneededforCineLabel   matlab.ui.control.Label
        egtiffcineetcLabel              matlab.ui.control.Label
        LoadImagesButton                matlab.ui.control.Button
        ImgRangeHigh                    matlab.ui.control.NumericEditField
        FlipImageLightingCheckBox       matlab.ui.control.CheckBox
        AssignImageSaveDirectoryButton  matlab.ui.control.Button
        GetImageDirectoryButton         matlab.ui.control.Button
        UIAxes_3                        matlab.ui.control.UIAxes
        ImageprocessingTab              matlab.ui.container.Tab
        ProcessImagesLabel              matlab.ui.control.Label
        BetaSlider                      matlab.ui.control.Slider
        BetaSliderLabel                 matlab.ui.control.Label
        AlphaSlider                     matlab.ui.control.Slider
        AlphaSliderLabel                matlab.ui.control.Label
        SigmaSlider                     matlab.ui.control.Slider
        SigmaSliderLabel                matlab.ui.control.Label
        HomomorphicfilterSwitch         matlab.ui.control.Switch
        HomomorphicfilterSwitchLabel    matlab.ui.control.Label
        WelcomeTextArea                 matlab.ui.control.TextArea
        WelcomeTextAreaLabel            matlab.ui.control.Label
        FrameSkipEditField              matlab.ui.control.EditField
        FrameSkipEditFieldLabel         matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        GammaSlider                     matlab.ui.control.Slider
        GammaSliderLabel                matlab.ui.control.Label
        AdjustHighSlider                matlab.ui.control.Slider
        AdjustHighSliderLabel           matlab.ui.control.Label
        AdjustLowSlider                 matlab.ui.control.Slider
        AdjustLowSliderLabel            matlab.ui.control.Label
        AmountSlider                    matlab.ui.control.Slider
        AmountSlider_3Label             matlab.ui.control.Label
        RadiusSlider                    matlab.ui.control.Slider
        RadiusSliderLabel               matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.EditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        ImageNumberSpinner              matlab.ui.control.Spinner
        ImageSharpeningSwitch           matlab.ui.control.Switch
        ImagecontrastadjustmentSwitchLabel  matlab.ui.control.Label
        ImageSharpeningSwitchLabel      matlab.ui.control.Label
        ImageNumberSpinnerLabel         matlab.ui.control.Label
        BackgroundSubtractionSwitch     matlab.ui.control.Switch
        BackgroundSubtractionSwitchLabel  matlab.ui.control.Label
        ImagecontrastadjustmentSwitch   matlab.ui.control.Switch
        GammaText                       matlab.ui.control.TextArea
        AdjustHighText                  matlab.ui.control.TextArea
        AdjustLowText                   matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        Default0Label                   matlab.ui.control.Label
        Default08Label                  matlab.ui.control.Label
        Default1Label                   matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        RollingAverageCheckBox          matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        IntWindowTestTab                matlab.ui.container.Tab
        TryitoutButton                  matlab.ui.control.Button
        ImageNumberSpinner_4            matlab.ui.control.Spinner
        ImageNumberSpinner_4Label       matlab.ui.control.Label
        IntWindowSizetoTryEditField     matlab.ui.control.NumericEditField
        IntWindowSizetoTryEditFieldLabel  matlab.ui.control.Label
        UIAxes_4                        matlab.ui.control.UIAxes
        PIVAnalysisTab                  matlab.ui.container.Tab
        WindowOverlapEditField          matlab.ui.control.NumericEditField
        WindowOverlapEditFieldLabel     matlab.ui.control.Label
        AnalyzeImagesLabel              matlab.ui.control.Label
        ImageNumberSpinner_3            matlab.ui.control.Spinner
        ImageNumberSpinner_3Label       matlab.ui.control.Label
        ofPassesEditField               matlab.ui.control.NumericEditField
        ofPassesEditFieldLabel          matlab.ui.control.Label
        PIVSettingsLabel                matlab.ui.control.Label
        rdWindowEditField               matlab.ui.control.NumericEditField
        rdWindowEditFieldLabel          matlab.ui.control.Label
        ndWindowEditField               matlab.ui.control.NumericEditField
        ndWindowEditFieldLabel          matlab.ui.control.Label
        stWindowEditField               matlab.ui.control.NumericEditField
        stWindowEditFieldLabel          matlab.ui.control.Label
        PassSizeLabel                   matlab.ui.control.Label
        SavesettingsButton              matlab.ui.control.Button
        RunPIVButton                    matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
    end


    properties (Access = public)
        imgPath
        RawImg % original image
        BgSubImg
        HFiltImg %Img after applying homomorphic filter
        CLAHEImg %
        AdjustedImg %Image after adjustments
        FinalImg %Image after all processing
        imgBitDepth %Bit depth of image given from directory
        imgSuffix %file type for the image. Different file types may have to be handled differently like .cine files
        Settings % Settings structure to be used for image processing function
        ImgToShow % Image to show on plot
        PIVIntImage
        PIVZoomROI
        ImgSaveDirec
    end

    methods (Access = private)


        function DrawZoomROI(app)
           value = app.ImageNumberSpinner_4.Value;
           if value == 0
                value = round(size(app.RawImg,3)/2);
           end
           app.ImageNumberSpinner_4.Value = value;
           figure
           imshow(app.PIVIntImage(:,:,value))
           roi = drawrectangle;
           ZoomROI = round(roi.Position);
           imshow(app.PIVIntImage(ZoomROI(2):ZoomROI(2)+ZoomROI(4),ZoomROI(1):ZoomROI(1)+ZoomROI(3),value),'parent',app.UIAxes_4);

           app.PIVZoomROI = ZoomROI;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             app.WindowSizeEditField = uieditfield('Visible','off');
            addpath(genpath("."))
            app.FrameSkipEditField.Value = num2str(1);
            if ~exist("..\Testing",'dir')
                mkdir("..\Testing")
            end
            app.BitShiftEditField.Value = num2str(0);

            drawnow;
            % app.UIFigure.WindowState = 'maximized';
            
        end

        % Button pushed function: GetImageDirectoryButton
        function GetImageDirectoryButtonPushed(app, event)
            path = uigetdir('*.*');
            %             NumOfImages = numel(file);
            app.FolderEditField.Value = path;
            app.imgPath = path;

        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            value = app.FolderEditField.Value;
            app.imgPath = value;
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            %get bit depth of images provided
            ImageFlip = app.FlipImageLightingCheckBox.Value;
            frame_list = app.ImgRangeLow.Value:app.ImgRangeHigh.Value;
            suffix = app.ExtensionEditField.Value;
            BitShift = str2double(app.BitShiftEditField.Value);
            app.RawImg = [];

            [Img,app.imgBitDepth] = LoadImages(app.imgPath,suffix,BitShift,frame_list,ImageFlip,app.UIFigure);
            NumOfImages = size(Img,3);
            %reset everything
            app.ImagecontrastadjustmentSwitch.Value = "Off";
            app.ImageSharpeningSwitch.Value = "Off";
            app.BgSubImg = []; app.AdjustedImg = []; app.FinalImg = []; app.HFiltImg
            %Display middle image
            app.ImageNumberSpinner.Value = round(NumOfImages/2);
            app.ImageNumberSpinner_2.Value = app.ImageNumberSpinner.Value;

            app.RawImg = Img;
            imshow(Img(:,:,round(NumOfImages/2)),'parent',app.UIAxes)
            imshow(Img(:,:,round(NumOfImages/2)),'parent',app.UIAxes_3)
        end

        % Value changed function: ImageNumberSpinner
        function ImageNumberSpinnerValueChanged(app, event)
            value = app.ImageNumberSpinner.Value;
            if ~isempty(app.FinalImg)
                imshow(app.FinalImg(:,:,value),'parent',app.UIAxes)
                imshow(app.FinalImg(:,:,value),'parent',app.UIAxes_3)
            elseif ~isempty(app.HFiltImg)
                imshow(app.HFiltImg(:,:,value),'parent',app.UIAxes)
                imshow(app.HFiltImg(:,:,value),'parent',app.UIAxes_3)
            elseif ~isempty(app.BgSubImg)
                imshow(app.BgSubImg(:,:,value),'parent',app.UIAxes)
                imshow(app.BgSubImg(:,:,value),'parent',app.UIAxes_3)
            else
                imshow(app.RawImg(:,:,value),'parent',app.UIAxes)
                imshow(app.RawImg(:,:,value),'parent',app.UIAxes_3)
            end

        end

        % Value changed function: ImageNumberSpinner_2
        function ImageNumberSpinner_2ValueChanged(app, event)
            value = app.ImageNumberSpinner_2.Value;
            
            app.ImageNumberSpinner.Value = value;
            ImageNumberSpinnerValueChanged(app,event)
        end

        % Value changed function: FlipImageLightingCheckBox
        function FlipImageLightingCheckBoxValueChanged(app, event)
            value = app.FlipImageLightingCheckBox.Value;

            %             app.RawImg = (2^app.imgBitDepth-1)-app.RawImg;
            app.RawImg = imcomplement(app.RawImg);
            if app.BackgroundSubtractionSwitch.Value == "On"
                BackgroundSubtractionSwitchValueChanged(app,event)
            else
                app.BgSubImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end

        end

        % Value changed function: RotateEditField
        function RotateEditFieldValueChanged(app, event)
            value = app.RotateEditField.Value;

            app.RawImg = rot90(app.RawImg,value);
            if app.BackgroundSubtractionSwitch.Value == "On"
                BackgroundSubtractionSwitchValueChanged(app,event)
            else
                app.BgSubImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end
        end

        % Value changed function: BackgroundSubtractionSwitch
        function BackgroundSubtractionSwitchValueChanged(app, event)
            value = app.BackgroundSubtractionSwitch.Value;
            if value == "On"
                rollingaverage = app.RollingAverageCheckBox.Value;
                rollingwindow = str2double(app.WindowSizeEditField.Value);
                frameskip = str2double(app.FrameSkipEditField.Value);

                if rollingaverage == 0
                    rollingwindow = 0;
                    app.WindowSizeEditField.Value = num2str(0);
                end
                [app.BgSubImg, BgTypical] = SubtractBackground(app.RawImg,frameskip,rollingwindow,app.UIFigure);
                ImageNumberSpinnerValueChanged(app,event)
                figure
                imshow(BgTypical)
                title('Example Background Image')
            else
                app.BgSubImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end

        end

        % Value changed function: AlphaSlider, BetaSlider, 
        % ...and 2 other components
        function HomomorphicfilterSwitchValueChanged(app, event)
            app.SigmaSlider.Visible = 'on'; app.AlphaSlider.Visible = 'on'; app.BetaSlider.Visible = 'on';
            value = app.HomomorphicfilterSwitch.Value;          
            if  app.BackgroundSubtractionSwitch.Value == "Off"
                Img = app.RawImg;
            else
                Img = app.BgSubImg;
            end
            Sigma = app.SigmaSlider.Value; Alpha = app.AlphaSlider.Value;
            Beta = app.BetaSlider.Value;
            if value == "On"
                app.HFiltImg = HomomorphicFilter(Img,Sigma,Alpha,Beta,app.imgBitDepth,app.UIFigure);
                ImageNumberSpinnerValueChanged(app,event)
            else
                app.HFiltImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end
        end

        % Value changed function: AdjustHighSlider, AdjustLowSlider, 
        % ...and 2 other components
        function ImagecontrastadjustmentSwitchValueChanged(app, event)
            value = app.ImagecontrastadjustmentSwitch.Value;
            if  app.BackgroundSubtractionSwitch.Value == "Off" & app.HomomorphicfilterSwitch.Value == "Off"
                Img = app.RawImg;
            elseif app.BackgroundSubtractionSwitch.Value == "On" & app.HomomorphicfilterSwitch.Value == "Off"
                Img = app.BgSubImg;
            else
                Img = app.HFiltImg;
            end

            if value == "On"
                adjusthigh = app.AdjustHighSlider.Value; app.AdjustHighText.Value = num2str(app.AdjustHighSlider.Value);
                adjustlow = app.AdjustLowSlider.Value; app.AdjustLowText.Value = num2str(app.AdjustLowSlider.Value);
                Gamma = app.GammaSlider.Value; app.GammaText.Value = num2str(app.GammaSlider.Value);

                app.AdjustedImg = ImageAdjust(Img,adjustlow,adjusthigh,Gamma,app.UIFigure);

                if app.ImageSharpeningSwitch.Value == "Off"
                    app.FinalImg = app.AdjustedImg;
                    ImageNumberSpinnerValueChanged(app,event)
                else
                    ImageSharpeningSwitchValueChanged(app,event)
                end
            else
                ImageNumberSpinnerValueChanged(app,event)
            end
        end

        % Value changed function: AmountSlider, ImageSharpeningSwitch, 
        % ...and 2 other components
        function ImageSharpeningSwitchValueChanged(app, event)
            value = app.ImageSharpeningSwitch.Value;
            if value == "On"
                Radius = app.RadiusSlider.Value;
                Amount = app.AmountSlider.Value;
                Threshold = app.ThresholdSlider.Value;

                %         ImagecontrastadjustmentSwitchValueChanged(app,event)
                app.FinalImg = ImageSharpening(app.RawImg,Radius,Amount,Threshold,app.UIFigure);
            else
                app.FinalImg = app.AdjustedImg;
            end
            
            ImageNumberSpinnerValueChanged(app,event)

        end

        % Button pushed function: RunPIVButton
        function RunPIVButtonPushed(app, event)

            imgNum = app.ImageNumberSpinner.Value;
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Tracking particles','Indeterminate','on');
            drawnow
            [x,y,u,v,u_filt,v_filt] = PIVlab_commandline(app.FinalImg,settings);
            Handle = app.UIAxes;
            imshow(app.FinalImg(:,:,imgNum),"parent",Handle)
            hold(Handle,'on')
            
            for i = 1:numel(tracks)
                [~,idx,~] = intersect(tracks(i).T,frame_range);
                if ~isempty(idx)
                    plot(Handle,tracks(i).X(idx),tracks(i).Y(idx),"-.",'linewidth',2,'MarkerFaceColor',[1 1 1],'MarkerSize',2)
                end
            end
            hold(Handle,'off')
            close(d)
        end

        % Button pushed function: SavesettingsButton
        function SaveSettingsButtonPushed(app, event)
            %Image brightness flip and bit shift and rotate
            app.Settings.FlipLighting = app.FlipImageLightingCheckBox.Value;
            app.Settings.BitShift = str2double(app.BitShiftEditField.Value);
            if ~isnan(str2double(app.RotateEditField.Value))
                app.Settings.Rotate = str2double(app.RotateEditField.Value);
            else
                app.Settings.Rotate = 0;
            end
            %Image background settings
            app.Settings.BgSubBool = app.BackgroundSubtractionSwitch.Value;
            app.Settings.FrameSkip = str2double(app.FrameSkipEditField.Value);
            app.Settings.RollingAverage = app.RollingAverageCheckBox.Value;
            app.Settings.RollingWindow = str2double(app.WindowSizeEditField.Value);
            %Image homomorphic filter settings
            app.Settings.ImHFiltBool = app.HomomorphicfilterSwitch.Value;
            app.Settings.Sigma = app.SigmaSlider.Value;
            app.Settings.Alpha = app.AlphaSlider.Value;
            app.Settings.Beta = app.BetaSlider.Value;
            %imadjust settings
            app.Settings.ImAdjustBool = app.ImagecontrastadjustmentSwitch.Value;
            app.Settings.adjustlow = app.AdjustLowSlider.Value;
            app.Settings.adjusthigh = app.AdjustHighSlider.Value;
            app.Settings.gamma = app.GammaSlider.Value;
            %imsharpen settings
            app.Settings.ImSharpBool = app.ImageSharpeningSwitch.Value;
            app.Settings.SharpenRadius = app.RadiusSlider.Value;
            app.Settings.SharpenAmount = app.AmountSlider.Value;
            app.Settings.SharpenThreshold = app.ThresholdSlider.Value;
            %Tracking Settings
            app.Settings.BinaryThresh = app.BinaryThresholdSlider.Value*2^app.imgBitDepth;
            app.Settings.area_lim = [str2double(app.ArealimLow.Value) str2double(app.ArealimHigh.Value)];
            app.Settings.max_disp = str2double(app.MaxDisplacementEditField.Value);

            settings = app.Settings;
            save('.\Testing\Settings.mat','settings')
        end

        % Button pushed function: TryitoutButton
        function TryitoutButtonPushed(app, event)
           IntWinSize = app.IntWindowSizetoTryEditField.Value;
           if ~isempty(app.FinalImg)
               Img = app.FinalImg;
           else
               Img = app.RawImg;
           end

           for i = 1:size(Img,3)
               I = Img(:,:,i);
               [rows, columns] = size(I);
               for row = 1 : IntWinSize : rows
                   I(row, :) = 2^16;
               end
               for col = 1 : IntWinSize : columns
                   I(:, col) = 2^16;
               end
               app.PIVIntImage(:,:,i) = I;
           end
           app.PIVIntImage = uint16(app.PIVIntImage);
         
           DrawZoomROI(app)
        end

        % Button down function: UIAxes_4
        function UIAxes_4ButtonDown(app, event)
            
        end

        % Value changed function: ImageNumberSpinner_4
        function ImageNumberSpinner_4ValueChanged(app, event)
            value = app.ImageNumberSpinner_4.Value;
          
            imshow(app.PIVIntImage(app.PIVZoomROI(2):app.PIVZoomROI(2)+app.PIVZoomROI(4),app.PIVZoomROI(1):app.PIVZoomROI(1)+app.PIVZoomROI(3),value),'parent',app.UIAxes_4)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadimagesTab = uitab(app.TabGroup);
            app.LoadimagesTab.Title = '1. Load images';
            app.UIAxes_3 = uiaxes(app.LoadimagesTab);
            title(app.UIAxes_3, 'Image')
            zlabel(app.UIAxes_3, 'Z')
            app.GetImageDirectoryButton = uibutton(app.LoadimagesTab, 'push');
            app.GetImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageDirectoryButtonPushed, true);
            app.GetImageDirectoryButton.Text = 'Get Image Directory';
            app.AssignImageSaveDirectoryButton = uibutton(app.LoadimagesTab, 'push');
            app.AssignImageSaveDirectoryButton.Text = 'Assign Image Save Directory';
            app.FlipImageLightingCheckBox = uicheckbox(app.LoadimagesTab);
            app.FlipImageLightingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipImageLightingCheckBoxValueChanged, true);
            app.FlipImageLightingCheckBox.Text = 'Flip Image Lighting?';
            app.ImgRangeHigh = uieditfield(app.LoadimagesTab, 'numeric');
            app.LoadImagesButton = uibutton(app.LoadimagesTab, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.egtiffcineetcLabel = uilabel(app.LoadimagesTab);
            app.egtiffcineetcLabel.Text = '(e.g ''.tiff'', ''.cine'', etc...)';
            app.UsuallyonlyneededforCineLabel = uilabel(app.LoadimagesTab);
            app.UsuallyonlyneededforCineLabel.Text = 'Usually only needed for .Cine';
            app.FolderEditFieldLabel = uilabel(app.LoadimagesTab);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.LoadimagesTab, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.ImageRangeEditFieldLabel = uilabel(app.LoadimagesTab);
            app.ImageRangeEditFieldLabel.Text = 'Image Range';
            app.ImgRangeLow = uieditfield(app.LoadimagesTab, 'numeric');
            app.ExtensionEditFieldLabel = uilabel(app.LoadimagesTab);
            app.ExtensionEditFieldLabel.Text = 'Extension';
            app.ExtensionEditField = uieditfield(app.LoadimagesTab, 'text');
            app.BitShiftEditFieldLabel = uilabel(app.LoadimagesTab);
            app.BitShiftEditFieldLabel.Text = 'BitShift';
            app.BitShiftEditField = uieditfield(app.LoadimagesTab, 'text');
            app.RotateEditFieldLabel = uilabel(app.LoadimagesTab);
            app.RotateEditFieldLabel.Text = 'Rotate ';
            app.RotateEditField = uieditfield(app.LoadimagesTab, 'text');
            app.RotateEditField.ValueChangedFcn = createCallbackFcn(app, @RotateEditFieldValueChanged, true);
            app.ImageNumberSpinner_2Label = uilabel(app.LoadimagesTab);
            app.ImageNumberSpinner_2Label.Text = 'Image Number';
            app.ImageNumberSpinner_2 = uispinner(app.LoadimagesTab);
            app.ImageNumberSpinner_2.ValueChangedFcn = createCallbackFcn(app, @ImageNumberSpinner_2ValueChanged, true);
            app.LoadImagesLabel = uilabel(app.LoadimagesTab);
            app.LoadImagesLabel.Text = 'Load Images';
            app.ImageprocessingTab = uitab(app.TabGroup);
            app.ImageprocessingTab.Title = '2. Image processing';
            app.UIAxes = uiaxes(app.ImageprocessingTab);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.RollingAverageCheckBox = uicheckbox(app.ImageprocessingTab);
            app.RollingAverageCheckBox.Text = 'Rolling Average';
            app.TextArea_2 = uitextarea(app.ImageprocessingTab);
            app.Default1Label = uilabel(app.ImageprocessingTab);
            app.Default1Label.Text = 'Default = 1';
            app.Default08Label = uilabel(app.ImageprocessingTab);
            app.Default08Label.Text = 'Default = 0.8';
            app.Default0Label = uilabel(app.ImageprocessingTab);
            app.Default0Label.Text = 'Default = 0';
            app.TextArea_3 = uitextarea(app.ImageprocessingTab);
            app.TextArea_4 = uitextarea(app.ImageprocessingTab);
            app.AdjustLowText = uitextarea(app.ImageprocessingTab);
            app.AdjustHighText = uitextarea(app.ImageprocessingTab);
            app.GammaText = uitextarea(app.ImageprocessingTab);
            app.ImagecontrastadjustmentSwitch = uiswitch(app.ImageprocessingTab, 'slider');
            app.ImagecontrastadjustmentSwitch.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.BackgroundSubtractionSwitchLabel = uilabel(app.ImageprocessingTab);
            app.BackgroundSubtractionSwitchLabel.Text = 'Background Subtraction';
            app.BackgroundSubtractionSwitch = uiswitch(app.ImageprocessingTab, 'slider');
            app.BackgroundSubtractionSwitch.ValueChangedFcn = createCallbackFcn(app, @BackgroundSubtractionSwitchValueChanged, true);
            app.ImageNumberSpinnerLabel = uilabel(app.ImageprocessingTab);
            app.ImageNumberSpinnerLabel.Text = 'Image Number';
            app.ImageSharpeningSwitchLabel = uilabel(app.ImageprocessingTab);
            app.ImageSharpeningSwitchLabel.Text = 'Image Sharpening';
            app.ImagecontrastadjustmentSwitchLabel = uilabel(app.ImageprocessingTab);
            app.ImagecontrastadjustmentSwitchLabel.Text = 'Image contrast adjustment';
            app.ImageSharpeningSwitch = uiswitch(app.ImageprocessingTab, 'slider');
            app.ImageSharpeningSwitch.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.ImageNumberSpinner = uispinner(app.ImageprocessingTab);
            app.ImageNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @ImageNumberSpinnerValueChanged, true);
            app.WindowSizeEditFieldLabel = uilabel(app.ImageprocessingTab);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.ImageprocessingTab, 'text');
            app.RadiusSliderLabel = uilabel(app.ImageprocessingTab);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.ImageprocessingTab);
            app.RadiusSlider.Limits = [0 5];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.RadiusSlider.Value = 1;
            app.AmountSlider_3Label = uilabel(app.ImageprocessingTab);
            app.AmountSlider_3Label.Text = 'Amount';
            app.AmountSlider = uislider(app.ImageprocessingTab);
            app.AmountSlider.Limits = [0 5];
            app.AmountSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.AmountSlider.Value = 0.8;
            app.AdjustLowSliderLabel = uilabel(app.ImageprocessingTab);
            app.AdjustLowSliderLabel.Text = 'Adjust Low';
            app.AdjustLowSlider = uislider(app.ImageprocessingTab);
            app.AdjustLowSlider.Limits = [0 1];
            app.AdjustLowSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.AdjustHighSliderLabel = uilabel(app.ImageprocessingTab);
            app.AdjustHighSliderLabel.Text = 'Adjust High';
            app.AdjustHighSlider = uislider(app.ImageprocessingTab);
            app.AdjustHighSlider.Limits = [0 1];
            app.AdjustHighSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.AdjustHighSlider.Value = 1;
            app.GammaSliderLabel = uilabel(app.ImageprocessingTab);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.ImageprocessingTab);
            app.GammaSlider.Limits = [0 5];
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.GammaSlider.Value = 1;
            app.ThresholdSliderLabel = uilabel(app.ImageprocessingTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.ImageprocessingTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.FrameSkipEditFieldLabel = uilabel(app.ImageprocessingTab);
            app.FrameSkipEditFieldLabel.Text = 'Frame Skip';
            app.FrameSkipEditField = uieditfield(app.ImageprocessingTab, 'text');
            app.WelcomeTextAreaLabel = uilabel(app.ImageprocessingTab);
            app.WelcomeTextAreaLabel.Text = 'Welcome';
            app.WelcomeTextArea = uitextarea(app.ImageprocessingTab);
            app.WelcomeTextArea.Value = {'Welcome to Miguel''s Image processing and PIV GUI. This GUI is meant to be used for a small subset of images so that it can run quickly. The idea is for you to be able to use the processing sliders below to quickly make changes to the images and see what they look like in real time. Try to use a few hundred images at maximum. '};
            app.HomomorphicfilterSwitchLabel = uilabel(app.ImageprocessingTab);
            app.HomomorphicfilterSwitchLabel.Text = 'Homomorphic filter';
            app.HomomorphicfilterSwitch = uiswitch(app.ImageprocessingTab, 'slider');
            app.HomomorphicfilterSwitch.ValueChangedFcn = createCallbackFcn(app, @HomomorphicfilterSwitchValueChanged, true);
            app.SigmaSliderLabel = uilabel(app.ImageprocessingTab);
            app.SigmaSliderLabel.Visible = 'off';
            app.SigmaSliderLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.ImageprocessingTab);
            app.SigmaSlider.ValueChangedFcn = createCallbackFcn(app, @HomomorphicfilterSwitchValueChanged, true);
            app.SigmaSlider.Visible = 'off';
            app.SigmaSlider.Value = 20;
            app.AlphaSliderLabel = uilabel(app.ImageprocessingTab);
            app.AlphaSliderLabel.Visible = 'off';
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.ImageprocessingTab);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @HomomorphicfilterSwitchValueChanged, true);
            app.AlphaSlider.Visible = 'off';
            app.BetaSliderLabel = uilabel(app.ImageprocessingTab);
            app.BetaSliderLabel.Visible = 'off';
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.ImageprocessingTab);
            app.BetaSlider.Limits = [1 10];
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @HomomorphicfilterSwitchValueChanged, true);
            app.BetaSlider.Visible = 'off';
            app.BetaSlider.Value = 1;
            app.ProcessImagesLabel = uilabel(app.ImageprocessingTab);
            app.ProcessImagesLabel.Text = 'Process Images';
            app.IntWindowTestTab = uitab(app.TabGroup);
            app.IntWindowTestTab.Title = '3. Int Window Test';
            app.UIAxes_4 = uiaxes(app.IntWindowTestTab);
            title(app.UIAxes_4, 'Image')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.ButtonDownFcn = createCallbackFcn(app, @UIAxes_4ButtonDown, true);
            app.IntWindowSizetoTryEditFieldLabel = uilabel(app.IntWindowTestTab);
            app.IntWindowSizetoTryEditFieldLabel.Text = 'Int Window Size to Try';
            app.IntWindowSizetoTryEditField = uieditfield(app.IntWindowTestTab, 'numeric');
            app.ImageNumberSpinner_4Label = uilabel(app.IntWindowTestTab);
            app.ImageNumberSpinner_4Label.Text = 'Image Number';
            app.ImageNumberSpinner_4 = uispinner(app.IntWindowTestTab);
            app.ImageNumberSpinner_4.ValueChangedFcn = createCallbackFcn(app, @ImageNumberSpinner_4ValueChanged, true);
            app.TryitoutButton = uibutton(app.IntWindowTestTab, 'push');
            app.TryitoutButton.ButtonPushedFcn = createCallbackFcn(app, @TryitoutButtonPushed, true);
            app.TryitoutButton.Text = 'Try it out';
            app.PIVAnalysisTab = uitab(app.TabGroup);
            app.PIVAnalysisTab.Title = '4. PIV Analysis';
            app.UIAxes_2 = uiaxes(app.PIVAnalysisTab);
            title(app.UIAxes_2, 'Image')
            zlabel(app.UIAxes_2, 'Z')
            app.RunPIVButton = uibutton(app.PIVAnalysisTab, 'push');
            app.RunPIVButton.ButtonPushedFcn = createCallbackFcn(app, @RunPIVButtonPushed, true);
            app.RunPIVButton.Text = 'Run PIV';
            app.SavesettingsButton = uibutton(app.PIVAnalysisTab, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.PassSizeLabel = uilabel(app.PIVAnalysisTab);
            app.PassSizeLabel.Text = 'Pass Size';
            app.stWindowEditFieldLabel = uilabel(app.PIVAnalysisTab);
            app.stWindowEditFieldLabel.Text = '1st Window';
            app.stWindowEditField = uieditfield(app.PIVAnalysisTab, 'numeric');
            app.ndWindowEditFieldLabel = uilabel(app.PIVAnalysisTab);
            app.ndWindowEditFieldLabel.Text = '2nd Window';
            app.ndWindowEditField = uieditfield(app.PIVAnalysisTab, 'numeric');
            app.rdWindowEditFieldLabel = uilabel(app.PIVAnalysisTab);
            app.rdWindowEditFieldLabel.Text = '3rd Window';
            app.rdWindowEditField = uieditfield(app.PIVAnalysisTab, 'numeric');
            app.PIVSettingsLabel = uilabel(app.PIVAnalysisTab);
            app.PIVSettingsLabel.Text = 'PIV Settings';
            app.ofPassesEditFieldLabel = uilabel(app.PIVAnalysisTab);
            app.ofPassesEditFieldLabel.Text = '# of Passes';
            app.ofPassesEditField = uieditfield(app.PIVAnalysisTab, 'numeric');
            app.ImageNumberSpinner_3Label = uilabel(app.PIVAnalysisTab);
            app.ImageNumberSpinner_3Label.Text = 'Image Number';
            app.ImageNumberSpinner_3 = uispinner(app.PIVAnalysisTab);
            app.AnalyzeImagesLabel = uilabel(app.PIVAnalysisTab);
            app.AnalyzeImagesLabel.Text = 'Analyze Images';
            app.WindowOverlapEditFieldLabel = uilabel(app.PIVAnalysisTab);
            app.WindowOverlapEditFieldLabel.Text = 'Window Overlap';
            app.WindowOverlapEditField = uieditfield(app.PIVAnalysisTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIPIVCode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end