classdef GUIParticleTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        MaxDisplacementY                matlab.ui.control.EditField
        WeightedDistAmplificationLow    matlab.ui.control.EditField
        ExpectedInitialDisplcamentLabel_2  matlab.ui.control.Label
        WeightedDistAmplificationHigh   matlab.ui.control.EditField
        ExpInitialDisplacementLow       matlab.ui.control.EditField
        ExpectedInitialDisplcamentLabel  matlab.ui.control.Label
        ExpInitialDisplacementHigh      matlab.ui.control.EditField
        CLAHECheckBox                   matlab.ui.control.CheckBox
        TrackLengthtoplotEditField      matlab.ui.control.EditField
        TrackLengthtoplotEditFieldLabel  matlab.ui.control.Label
        BetaSlider                      matlab.ui.control.Slider
        BetaSliderLabel                 matlab.ui.control.Label
        AlphaSlider                     matlab.ui.control.Slider
        AlphaSliderLabel                matlab.ui.control.Label
        SigmaSlider                     matlab.ui.control.Slider
        SigmaSliderLabel                matlab.ui.control.Label
        HomomorphicfilterSwitch         matlab.ui.control.Switch
        HomomorphicfilterSwitchLabel    matlab.ui.control.Label
        TestBinaryThreshCheckBox        matlab.ui.control.CheckBox
        RotateEditField                 matlab.ui.control.EditField
        RotateEditFieldLabel            matlab.ui.control.Label
        UsuallyonlyneededforCineLabel   matlab.ui.control.Label
        BitShiftEditField               matlab.ui.control.EditField
        BitShiftEditFieldLabel          matlab.ui.control.Label
        egtiffcineetcLabel              matlab.ui.control.Label
        ExtensionEditField              matlab.ui.control.EditField
        ExtensionEditFieldLabel         matlab.ui.control.Label
        WelcomeTextArea                 matlab.ui.control.TextArea
        WelcomeTextAreaLabel            matlab.ui.control.Label
        LoadImagesButton                matlab.ui.control.Button
        ImgRangeHigh                    matlab.ui.control.NumericEditField
        ImgRangeLow                     matlab.ui.control.NumericEditField
        ImageRangeEditFieldLabel        matlab.ui.control.Label
        FolderEditField                 matlab.ui.control.EditField
        FolderEditFieldLabel            matlab.ui.control.Label
        FrameSkipEditField              matlab.ui.control.EditField
        FrameSkipEditFieldLabel         matlab.ui.control.Label
        FlipImageLightingCheckBox       matlab.ui.control.CheckBox
        MaxDisplacementX                matlab.ui.control.EditField
        MaxDisplacementEditFieldLabel   matlab.ui.control.Label
        ArealimHigh                     matlab.ui.control.EditField
        ArealimLow                      matlab.ui.control.EditField
        ArealimEditFieldLabel           matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        GammaSlider                     matlab.ui.control.Slider
        GammaSliderLabel                matlab.ui.control.Label
        AdjustHighSlider                matlab.ui.control.Slider
        AdjustHighSliderLabel           matlab.ui.control.Label
        AdjustLowSlider                 matlab.ui.control.Slider
        AdjustLowSliderLabel            matlab.ui.control.Label
        AmountSlider                    matlab.ui.control.Slider
        AmountSlider_3Label             matlab.ui.control.Label
        RadiusSlider                    matlab.ui.control.Slider
        RadiusSliderLabel               matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.EditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        ImageNumberSpinner              matlab.ui.control.Spinner
        ImageSharpeningSwitch           matlab.ui.control.Switch
        ImagecontrastadjustmentSwitchLabel  matlab.ui.control.Label
        ImageSharpeningSwitchLabel      matlab.ui.control.Label
        ImageNumberSpinnerLabel         matlab.ui.control.Label
        BackgroundSubtractionSwitch     matlab.ui.control.Switch
        BackgroundSubtractionSwitchLabel  matlab.ui.control.Label
        ImagecontrastadjustmentSwitch   matlab.ui.control.Switch
        BinaryThresholdSlider           matlab.ui.control.Slider
        BinaryThresholdSliderLabel      matlab.ui.control.Label
        GammaText                       matlab.ui.control.TextArea
        AdjustHighText                  matlab.ui.control.TextArea
        AdjustLowText                   matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        Default0Label                   matlab.ui.control.Label
        Default08Label                  matlab.ui.control.Label
        Default1Label                   matlab.ui.control.Label
        AssignImageSaveDirectoryButton  matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        RollingAverageCheckBox          matlab.ui.control.CheckBox
        SavesettingsButton              matlab.ui.control.Button
        TrackButton                     matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        GetImageDirectoryButton         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)
        imgPath
        RawImg % original image
        BgSubImg
        CLAHEImg %
        SharpenImage
        FinalImg %Image after all processing
        imgBitDepth %Bit depth of image given from directory
        imgSuffix %file type for the image. Different file types may have to be handled differently like .cine files
        Settings % Settings structure to be used for image processing function
        ImgToShow % Image to show on plot
        ImgSaveDirec
    end

    methods (Access = private)
        function FindFinalImage(app)
            if ~isempty(app.CLAHEImg)
                app.FinalImg = app.CLAHEImg;
            elseif ~isempty(app.SharpenImage)
                app.FinalImg = app.SharpenImage;
            elseif ~isempty(app.BgSubImg)
                app.FinalImg = app.BgSubImg;
            else
                app.FinalImg = app.RawImg;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %             app.WindowSizeEditField = uieditfield('Visible','off');
            addpath(genpath("."))
            app.FrameSkipEditField.Value = num2str(1);
            if ~exist("..\Testing",'dir')
                mkdir("..\Testing")
            end
            app.BitShiftEditField.Value = num2str(0);

            drawnow;
            % app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: GetImageDirectoryButton
        function GetImageDirectoryButtonPushed(app, event)
            path = uigetdir('*.*');
            %             NumOfImages = numel(file);
            app.FolderEditField.Value = path;
            app.imgPath = path;

        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            value = app.FolderEditField.Value;
            app.imgPath = value;
        end

        % Button pushed function: LoadImagesButton
        function LoadImagesButtonPushed(app, event)
            %get bit depth of images provided
            ImageFlip = app.FlipImageLightingCheckBox.Value;
            frame_list = app.ImgRangeLow.Value:app.ImgRangeHigh.Value;
            suffix = app.ExtensionEditField.Value;
            BitShift = str2double(app.BitShiftEditField.Value);
            app.RawImg = [];

            [Img,app.imgBitDepth] = LoadImages(app.imgPath,suffix,BitShift,frame_list,ImageFlip,app.UIFigure);
            NumOfImages = size(Img,3);
            %reset everything
            app.ImageSharpeningSwitch.Value = "Off";
            app.BgSubImg = []; app.SharpenImage = []; app.FinalImg = []; 
            %Display middle image
            app.ImageNumberSpinner.Value = round(NumOfImages/2);

            app.RawImg = Img;
            imshow(Img(:,:,round(NumOfImages/2)),'parent',app.UIAxes)
        end

        % Value changed function: ImageNumberSpinner
        function ImageNumberSpinnerValueChanged(app, event)
            value = app.ImageNumberSpinner.Value;

            FindFinalImage(app)
            imshow(app.FinalImg(:,:,value),'parent',app.UIAxes)
        end

        % Value changed function: FlipImageLightingCheckBox
        function FlipImageLightingCheckBoxValueChanged(app, event)
            value = app.FlipImageLightingCheckBox.Value;

            %             app.RawImg = (2^app.imgBitDepth-1)-app.RawImg;
            app.RawImg = imcomplement(app.RawImg);
            if app.BackgroundSubtractionSwitch.Value == "On"
                BackgroundSubtractionSwitchValueChanged(app,event)
            else
                app.BgSubImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end

        end

        % Value changed function: RotateEditField
        function RotateEditFieldValueChanged(app, event)
            value = app.RotateEditField.Value;

            app.RawImg = rot90(app.RawImg,value);
            if app.BackgroundSubtractionSwitch.Value == "On"
                BackgroundSubtractionSwitchValueChanged(app,event)
            else
                app.BgSubImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end
        end

        % Value changed function: BackgroundSubtractionSwitch
        function BackgroundSubtractionSwitchValueChanged(app, event)
            value = app.BackgroundSubtractionSwitch.Value;
            if value == "On"
                rollingaverage = app.RollingAverageCheckBox.Value;
                rollingwindow = str2double(app.WindowSizeEditField.Value);
                frameskip = str2double(app.FrameSkipEditField.Value);

                if rollingaverage == 0
                    rollingwindow = 0;
                    app.WindowSizeEditField.Value = num2str(0);
                end
                [app.BgSubImg] = SubtractBackground(app.RawImg,frameskip,rollingwindow,app.UIFigure);
                ImageNumberSpinnerValueChanged(app,event)
                % figure
                % imshow(BgTypical)
                % title('Example Background Image')
            else
                app.BgSubImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end

        end

        % Value changed function: AlphaSlider, BetaSlider, 
        % ...and 2 other components
        function HomomorphicfilterSwitchValueChanged(app, event)
            value = app.HomomorphicfilterSwitch.Value;          
            FindFinalImage(app)

            Sigma = app.SigmaSlider.Value; Alpha = app.AlphaSlider.Value;
            Beta = app.BetaSlider.Value;
            if value == "On"
                app.HFiltImg = HomomorphicFilter(Img,Sigma,Alpha,Beta,app.imgBitDepth,app.UIFigure);
                ImageNumberSpinnerValueChanged(app,event)
            else
                app.HFiltImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end
        end

        % Value changed function: AdjustHighSlider, AdjustLowSlider, 
        % ...and 2 other components
        function ImagecontrastadjustmentSwitchValueChanged(app, event)
            value = app.ImagecontrastadjustmentSwitch.Value;
            FindFinalImage(app)

            if value == "On"
                adjusthigh = app.AdjustHighSlider.Value; app.AdjustHighText.Value = num2str(app.AdjustHighSlider.Value);
                adjustlow = app.AdjustLowSlider.Value; app.AdjustLowText.Value = num2str(app.AdjustLowSlider.Value);
                Gamma = app.GammaSlider.Value; app.GammaText.Value = num2str(app.GammaSlider.Value);

                app.AdjustedImg = ImageAdjust(Img,adjustlow,adjusthigh,Gamma,app.UIFigure);

                if app.ImageSharpeningSwitch.Value == "Off"
                    app.FinalImg = app.AdjustedImg;
                    ImageNumberSpinnerValueChanged(app,event)
                else
                    ImageSharpeningSwitchValueChanged(app,event)
                end
            else
                app.AdjustedImg = [];
                ImageNumberSpinnerValueChanged(app,event)
            end
        end

        % Value changed function: AmountSlider, ImageSharpeningSwitch, 
        % ...and 2 other components
        function ImageSharpeningSwitchValueChanged(app, event)
            value = app.ImageSharpeningSwitch.Value;
            FindFinalImage(app)
            if value == "On"
                Radius = app.RadiusSlider.Value;
                Amount = app.AmountSlider.Value;
                Threshold = app.ThresholdSlider.Value;

                %         ImagecontrastadjustmentSwitchValueChanged(app,event)
                app.SharpenImage = ImageSharpening(app.FinalImg,Radius,Amount,Threshold,app.UIFigure);
            else
                app.SharpenImage = [];
            end

            ImageNumberSpinnerValueChanged(app,event)

        end

        % Value changed function: BinaryThresholdSlider, 
        % ...and 1 other component
        function BinaryThresholdSliderValueChanged(app, event)
            value = app.BinaryThresholdSlider.Value;
            app.TextArea.Value = num2str(value);
            NumOfImage = app.ImageNumberSpinner.Value;
            FindFinalImage(app)

            if app.TestBinaryThreshCheckBox.Value
                imshow(app.FinalImg(:,:,NumOfImage)>value*2^app.imgBitDepth,'parent',app.UIAxes);
            end
        end

        % Button pushed function: TrackButton
        function TrackButtonPushed(app, event)
            area_lim = [str2double(app.ArealimLow.Value) str2double(app.ArealimHigh.Value)];
            threshold = app.BinaryThresholdSlider.Value*2^app.imgBitDepth;
            if isnan(str2double (app.MaxDisplacementX.Value))
                max_disp = str2double(app.MaxDisplacementX.Value);
            else
                max_disp = [str2double(app.MaxDisplacementX.Value) str2double(app.MaxDisplacementY.Value)];
            end
            
            ExpInitialDisplacement = [str2double(app.ExpInitialDisplacementLow.Value) str2double(app.ExpInitialDisplacementHigh.Value)];
            WeightedDistAmplification = [str2double(app.WeightedDistAmplificationLow.Value) str2double(app.WeightedDistAmplificationHigh.Value)];

            imgNum = app.ImageNumberSpinner.Value;
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Tracking particles','Indeterminate','on');
            drawnow
            [x,y,t,ang]=ParticleFinder(app.FinalImg,threshold,app.ImgRangeLow.Value:app.ImgRangeHigh.Value,[],[],area_lim,0,0);
            [vtracks,~,~,~,tracks] = PredictiveTracker(x,y,t,ang,max_disp,area_lim,ExpInitialDisplacement,WeightedDistAmplification);
            Handle = app.UIAxes;
            imshow(app.FinalImg(:,:,imgNum),"parent",Handle)
            hold(Handle,'on')
            LoadedFrameRange = app.ImgRangeLow.Value:app.ImgRangeHigh.Value;
            if imgNum+10>size(app.FinalImg,3)
                frame_range = LoadedFrameRange(imgNum:size(app.FinalImg,3));
            else
                frame_range = LoadedFrameRange(imgNum:imgNum+str2double(app.TrackLengthtoplotEditField.Value));
            end
            
            for i = 1:numel(tracks)
                MemberBool = ismember(tracks(i).T,frame_range);
                idx = find(MemberBool);
                if ~isempty(idx)
                    plot(Handle,tracks(i).X(idx),tracks(i).Y(idx),"-.",'linewidth',2,'MarkerFaceColor',[1 1 1],'MarkerSize',2)
                end
            end
            hold(Handle,'off')
      
            close(d)
        end

        % Button pushed function: SavesettingsButton
        function SaveSettingsButtonPushed(app, event)
            %Image brightness flip and bit shift and rotate
            app.Settings.FlipLighting = app.FlipImageLightingCheckBox.Value;
            app.Settings.BitShift = str2double(app.BitShiftEditField.Value);
            if ~isnan(str2double(app.RotateEditField.Value))
                app.Settings.Rotate = str2double(app.RotateEditField.Value);
            else
                app.Settings.Rotate = 0;
            end
            %Image background settings
            app.Settings.BgSubBool = app.BackgroundSubtractionSwitch.Value;
            app.Settings.FrameSkip = str2double(app.FrameSkipEditField.Value);
            app.Settings.RollingAverage = app.RollingAverageCheckBox.Value;
            app.Settings.RollingWindow = str2double(app.WindowSizeEditField.Value);
            %Image homomorphic filter settings
            app.Settings.ImHFiltBool = app.HomomorphicfilterSwitch.Value;
            app.Settings.Sigma = app.SigmaSlider.Value;
            app.Settings.Alpha = app.AlphaSlider.Value;
            app.Settings.Beta = app.BetaSlider.Value;
            %imadjust settings
            app.Settings.ImAdjustBool = app.ImagecontrastadjustmentSwitch.Value;
            app.Settings.adjustlow = app.AdjustLowSlider.Value;
            app.Settings.adjusthigh = app.AdjustHighSlider.Value;
            app.Settings.gamma = app.GammaSlider.Value;
            %imsharpen settings
            app.Settings.ImSharpBool = app.ImageSharpeningSwitch.Value;
            app.Settings.SharpenRadius = app.RadiusSlider.Value;
            app.Settings.SharpenAmount = app.AmountSlider.Value;
            app.Settings.SharpenThreshold = app.ThresholdSlider.Value;
            %Tracking Settings
            app.Settings.BinaryThresh = app.BinaryThresholdSlider.Value*2^app.imgBitDepth;
            app.Settings.area_lim = [str2double(app.ArealimLow.Value) str2double(app.ArealimHigh.Value)];
            app.Settings.max_disp = str2double(app.MaxDisplacementX.Value);

            settings = app.Settings;
            save('.\Testing\Settings.mat','settings')
        end

        % Value changed function: CLAHECheckBox
        function CLAHECheckBoxValueChanged(app, event)

            value = app.CLAHECheckBox.Value;
            FindFinalImage(app)
            if value == 1
                app.CLAHEImg = CLAHEProcess(app.FinalImg,app.UIFigure);
            elseif value == 0
                app.CLAHEImg = [];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = '1. Start';
            app.UIAxes = uiaxes(app.StartTab);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.GetImageDirectoryButton = uibutton(app.StartTab, 'push');
            app.GetImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageDirectoryButtonPushed, true);
            app.GetImageDirectoryButton.Text = 'Get Image Directory';
            app.TextArea = uitextarea(app.StartTab);
            app.TrackButton = uibutton(app.StartTab, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @TrackButtonPushed, true);
            app.TrackButton.Text = 'Track';
            app.SavesettingsButton = uibutton(app.StartTab, 'push');
            app.SavesettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SavesettingsButton.Text = 'Save settings';
            app.RollingAverageCheckBox = uicheckbox(app.StartTab);
            app.RollingAverageCheckBox.Text = 'Rolling Average';
            app.TextArea_2 = uitextarea(app.StartTab);
            app.AssignImageSaveDirectoryButton = uibutton(app.StartTab, 'push');
            app.AssignImageSaveDirectoryButton.Text = 'Assign Image Save Directory';
            app.Default1Label = uilabel(app.StartTab);
            app.Default1Label.Text = 'Default = 1';
            app.Default08Label = uilabel(app.StartTab);
            app.Default08Label.Text = 'Default = 0.8';
            app.Default0Label = uilabel(app.StartTab);
            app.Default0Label.Text = 'Default = 0';
            app.TextArea_3 = uitextarea(app.StartTab);
            app.TextArea_4 = uitextarea(app.StartTab);
            app.AdjustLowText = uitextarea(app.StartTab);
            app.AdjustHighText = uitextarea(app.StartTab);
            app.GammaText = uitextarea(app.StartTab);
            app.BinaryThresholdSliderLabel = uilabel(app.StartTab);
            app.BinaryThresholdSliderLabel.Text = 'Binary Threshold';
            app.BinaryThresholdSlider = uislider(app.StartTab);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.ImagecontrastadjustmentSwitch = uiswitch(app.StartTab, 'slider');
            app.ImagecontrastadjustmentSwitch.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.BackgroundSubtractionSwitchLabel = uilabel(app.StartTab);
            app.BackgroundSubtractionSwitchLabel.Text = 'Background Subtraction';
            app.BackgroundSubtractionSwitch = uiswitch(app.StartTab, 'slider');
            app.BackgroundSubtractionSwitch.ValueChangedFcn = createCallbackFcn(app, @BackgroundSubtractionSwitchValueChanged, true);
            app.ImageNumberSpinnerLabel = uilabel(app.StartTab);
            app.ImageNumberSpinnerLabel.Text = 'Image Number';
            app.ImageSharpeningSwitchLabel = uilabel(app.StartTab);
            app.ImageSharpeningSwitchLabel.Text = 'Image Sharpening';
            app.ImagecontrastadjustmentSwitchLabel = uilabel(app.StartTab);
            app.ImagecontrastadjustmentSwitchLabel.Text = 'Image contrast adjustment';
            app.ImageSharpeningSwitch = uiswitch(app.StartTab, 'slider');
            app.ImageSharpeningSwitch.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.ImageNumberSpinner = uispinner(app.StartTab);
            app.ImageNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @ImageNumberSpinnerValueChanged, true);
            app.WindowSizeEditFieldLabel = uilabel(app.StartTab);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.StartTab, 'text');
            app.RadiusSliderLabel = uilabel(app.StartTab);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.StartTab);
            app.RadiusSlider.Limits = [0 5];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.RadiusSlider.Value = 1;
            app.AmountSlider_3Label = uilabel(app.StartTab);
            app.AmountSlider_3Label.Text = 'Amount';
            app.AmountSlider = uislider(app.StartTab);
            app.AmountSlider.Limits = [0 5];
            app.AmountSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.AmountSlider.Value = 0.8;
            app.AdjustLowSliderLabel = uilabel(app.StartTab);
            app.AdjustLowSliderLabel.Text = 'Adjust Low';
            app.AdjustLowSlider = uislider(app.StartTab);
            app.AdjustLowSlider.Limits = [0 1];
            app.AdjustLowSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.AdjustHighSliderLabel = uilabel(app.StartTab);
            app.AdjustHighSliderLabel.Text = 'Adjust High';
            app.AdjustHighSlider = uislider(app.StartTab);
            app.AdjustHighSlider.Limits = [0 1];
            app.AdjustHighSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.AdjustHighSlider.Value = 1;
            app.GammaSliderLabel = uilabel(app.StartTab);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.StartTab);
            app.GammaSlider.Limits = [0 5];
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.GammaSlider.Value = 1;
            app.ThresholdSliderLabel = uilabel(app.StartTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.StartTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.ArealimEditFieldLabel = uilabel(app.StartTab);
            app.ArealimEditFieldLabel.Text = 'Arealim';
            app.ArealimLow = uieditfield(app.StartTab, 'text');
            app.ArealimHigh = uieditfield(app.StartTab, 'text');
            app.MaxDisplacementEditFieldLabel = uilabel(app.StartTab);
            app.MaxDisplacementEditFieldLabel.Text = 'Max Displacement';
            app.MaxDisplacementX = uieditfield(app.StartTab, 'text');
            app.FlipImageLightingCheckBox = uicheckbox(app.StartTab);
            app.FlipImageLightingCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipImageLightingCheckBoxValueChanged, true);
            app.FlipImageLightingCheckBox.Text = 'Flip Image Lighting?';
            app.FrameSkipEditFieldLabel = uilabel(app.StartTab);
            app.FrameSkipEditFieldLabel.Text = 'Frame Skip';
            app.FrameSkipEditField = uieditfield(app.StartTab, 'text');
            app.FolderEditFieldLabel = uilabel(app.StartTab);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.StartTab, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.ImageRangeEditFieldLabel = uilabel(app.StartTab);
            app.ImageRangeEditFieldLabel.Text = 'Image Range';
            app.ImgRangeLow = uieditfield(app.StartTab, 'numeric');
            app.ImgRangeHigh = uieditfield(app.StartTab, 'numeric');
            app.LoadImagesButton = uibutton(app.StartTab, 'push');
            app.LoadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImagesButtonPushed, true);
            app.LoadImagesButton.Text = 'Load Images';
            app.WelcomeTextAreaLabel = uilabel(app.StartTab);
            app.WelcomeTextAreaLabel.Text = 'Welcome';
            app.WelcomeTextArea = uitextarea(app.StartTab);
            app.WelcomeTextArea.Value = {'Welcome to Miguel''s Image processing and tracking GUI. This GUI is meant to be used for a small subset of images so that it can run quickly. The idea is for you to be able to use the processing sliders below to quickly make changes to the images and see what they look like in real time. Try to use a few hundred images at maximum. '};
            app.ExtensionEditFieldLabel = uilabel(app.StartTab);
            app.ExtensionEditFieldLabel.Text = 'Extension';
            app.ExtensionEditField = uieditfield(app.StartTab, 'text');
            app.egtiffcineetcLabel = uilabel(app.StartTab);
            app.egtiffcineetcLabel.Text = '(e.g ''.tiff'', ''.cine'', etc...)';
            app.BitShiftEditFieldLabel = uilabel(app.StartTab);
            app.BitShiftEditFieldLabel.Text = 'BitShift';
            app.BitShiftEditField = uieditfield(app.StartTab, 'text');
            app.UsuallyonlyneededforCineLabel = uilabel(app.StartTab);
            app.UsuallyonlyneededforCineLabel.Text = 'Usually only needed for .Cine';
            app.RotateEditFieldLabel = uilabel(app.StartTab);
            app.RotateEditFieldLabel.Text = 'Rotate ';
            app.RotateEditField = uieditfield(app.StartTab, 'text');
            app.RotateEditField.ValueChangedFcn = createCallbackFcn(app, @RotateEditFieldValueChanged, true);
            app.TestBinaryThreshCheckBox = uicheckbox(app.StartTab);
            app.TestBinaryThreshCheckBox.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.TestBinaryThreshCheckBox.Text = 'Test Binary Thresh';
            app.HomomorphicfilterSwitchLabel = uilabel(app.StartTab);
            app.HomomorphicfilterSwitchLabel.Text = 'Homomorphic filter';
            app.HomomorphicfilterSwitch = uiswitch(app.StartTab, 'slider');
            app.HomomorphicfilterSwitch.ValueChangedFcn = createCallbackFcn(app, @HomomorphicfilterSwitchValueChanged, true);
            app.SigmaSliderLabel = uilabel(app.StartTab);
            app.SigmaSliderLabel.Text = 'Sigma';
            app.SigmaSlider = uislider(app.StartTab);
            app.SigmaSlider.ValueChangedFcn = createCallbackFcn(app, @HomomorphicfilterSwitchValueChanged, true);
            app.SigmaSlider.Value = 20;
            app.AlphaSliderLabel = uilabel(app.StartTab);
            app.AlphaSliderLabel.Text = 'Alpha';
            app.AlphaSlider = uislider(app.StartTab);
            app.AlphaSlider.Limits = [0 1];
            app.AlphaSlider.ValueChangedFcn = createCallbackFcn(app, @HomomorphicfilterSwitchValueChanged, true);
            app.BetaSliderLabel = uilabel(app.StartTab);
            app.BetaSliderLabel.Text = 'Beta';
            app.BetaSlider = uislider(app.StartTab);
            app.BetaSlider.Limits = [1 10];
            app.BetaSlider.ValueChangedFcn = createCallbackFcn(app, @HomomorphicfilterSwitchValueChanged, true);
            app.BetaSlider.Value = 1;
            app.TrackLengthtoplotEditFieldLabel = uilabel(app.StartTab);
            app.TrackLengthtoplotEditFieldLabel.Text = 'Track Length to plot';
            app.TrackLengthtoplotEditField = uieditfield(app.StartTab, 'text');
            app.CLAHECheckBox = uicheckbox(app.StartTab);
            app.CLAHECheckBox.ValueChangedFcn = createCallbackFcn(app, @CLAHECheckBoxValueChanged, true);
            app.CLAHECheckBox.Text = 'CLAHE';
            app.ExpInitialDisplacementHigh = uieditfield(app.StartTab, 'text');
            app.ExpInitialDisplacementHigh.Value = '0';
            app.ExpectedInitialDisplcamentLabel = uilabel(app.StartTab);
            app.ExpectedInitialDisplcamentLabel.Text = 'Expected Initial Displcament';
            app.ExpInitialDisplacementLow = uieditfield(app.StartTab, 'text');
            app.ExpInitialDisplacementLow.Value = '0';
            app.WeightedDistAmplificationHigh = uieditfield(app.StartTab, 'text');
            app.WeightedDistAmplificationHigh.Value = '1';
            app.ExpectedInitialDisplcamentLabel_2 = uilabel(app.StartTab);
            app.ExpectedInitialDisplcamentLabel_2.Text = 'Weighted Distance Amplification';
            app.WeightedDistAmplificationLow = uieditfield(app.StartTab, 'text');
            app.WeightedDistAmplificationLow.Value = '1';
            app.MaxDisplacementY = uieditfield(app.StartTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIParticleTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end