classdef GUIParticleTracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        StartTab                        matlab.ui.container.Tab
        MaxDisplacementEditField        matlab.ui.control.EditField
        MaxDisplacementEditFieldLabel   matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        ArealimEditField                matlab.ui.control.EditField
        ArealimEditFieldLabel           matlab.ui.control.Label
        ThresholdSlider                 matlab.ui.control.Slider
        ThresholdSliderLabel            matlab.ui.control.Label
        GammaSlider                     matlab.ui.control.Slider
        GammaSliderLabel                matlab.ui.control.Label
        CheckBinarizedCheckBox          matlab.ui.control.CheckBox
        AdjustHighSlider                matlab.ui.control.Slider
        AdjustHighSliderLabel           matlab.ui.control.Label
        AdjustLowSlider                 matlab.ui.control.Slider
        AdjustLowSliderLabel            matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        AmountSlider                    matlab.ui.control.Slider
        AmountSlider_3Label             matlab.ui.control.Label
        RadiusSlider                    matlab.ui.control.Slider
        RadiusSliderLabel               matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.EditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        ImageNumberSpinner              matlab.ui.control.Spinner
        ImageNumberSpinnerLabel         matlab.ui.control.Label
        ImageSharpeningSwitch           matlab.ui.control.Switch
        ImagecontrastadjustmentSwitchLabel  matlab.ui.control.Label
        ImageSharpeningSwitchLabel      matlab.ui.control.Label
        BackgroundSubtractionSwitch     matlab.ui.control.Switch
        BackgroundSubtractionSwitchLabel  matlab.ui.control.Label
        BinaryThresholdSlider           matlab.ui.control.Slider
        ImagecontrastadjustmentSwitch   matlab.ui.control.Switch
        BinaryThresholdSliderLabel      matlab.ui.control.Label
        TextArea_7                      matlab.ui.control.TextArea
        TextArea_6                      matlab.ui.control.TextArea
        TextArea_5                      matlab.ui.control.TextArea
        TextArea_4                      matlab.ui.control.TextArea
        TextArea_3                      matlab.ui.control.TextArea
        Default0Label                   matlab.ui.control.Label
        Default08Label                  matlab.ui.control.Label
        Default1Label                   matlab.ui.control.Label
        TextArea_2                      matlab.ui.control.TextArea
        AssignImageSaveDirectoryButton  matlab.ui.control.Button
        RollingAverageCheckBox          matlab.ui.control.CheckBox
        SavesettingsButton              matlab.ui.control.Button
        TrackButton                     matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        GetImageDirectoryButton         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        BackgroundTab                   matlab.ui.container.Tab
        ContrastTab                     matlab.ui.container.Tab
        SharpenTab                      matlab.ui.container.Tab
    end

    
    properties (Access = public)
        RawImg % original image
        AdjustedImg %Image after adjustments
        FinalImg %Image after all processing
        imgBitDepth %Bit depth of image given from directory
        imgSuffix %file type for the image. Different file types may have to be handled differently like .cine files
        Settings % Settings structure to be used for image processing function
        ImgToShow % Image to show on plot
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.WindowSizeEditField = uieditfield('Visible','off');
           addpath(".\Image Processing Functions")
        end

        % Value changed function: BinaryThresholdSlider
        function BinaryThresholdSliderValueChanged(app, event)
%             value = app.BinaryThresholdSlider.Value;
%             app.TextArea.Value = num2str(value);
%             
% %             imshow(app.RawImg>value*2^app.imgBitDepth,'parent',app.UIAxes);
        end

        % Button pushed function: GetImageDirectoryButton
        function GetImageDirectoryButtonPushed(app, event)
            [file,path] = uigetfile('*.*','Select One or Multiple Images','MultiSelect','on');
            NumOfImages = numel(file);

            %get bit depth of images provided

            imgInfo = imfinfo(fullfile(path,file{1}));
            app.imgBitDepth = imgInfo.BitDepth;
            
            FirstImg = imread(fullfile(path,file{1}));
            BitClass = class(FirstImg);
            Img = zeros([size(FirstImg) NumOfImages],BitClass);
            for i = 1:NumOfImages
                Img(:,:,i) = imread(fullfile(path,file{i}));
            end
     
            app.ImageNumberSpinner.Value = round(NumOfImages/2);
            app.RawImg = Img;
            imshow(Img(:,:,round(NumOfImages/2)),'parent',app.UIAxes)

        end

        % Value changed function: ImageNumberSpinner
        function ImageNumberSpinnerValueChanged(app, event)
            value = app.ImageNumberSpinner.Value;
        if ~isempty(app.FinalImg)
           imshow(app.FinalImg(:,:,value),'parent',app.UIAxes)
        else 
            imshow(app.RawImg(:,:,value),'parent',app.UIAxes)
        end
        end

        % Value changed function: BackgroundSubtractionSwitch
        function BackgroundSubtractionSwitchValueChanged(app, event)
            value = app.BackgroundSubtractionSwitch.Value;


        end

        % Value changed function: RollingAverageCheckBox
        function RollingAverageCheckBoxValueChanged(app, event)
            value = app.RollingAverageCheckBox.Value; 
            if value == 1
%                 app.WindowSizeEditField = uieditfield('Visible','on');
%                 app.WindowSizeEditFieldLabel = uilabel('Visibile','on');
                app.WindowSizeEditField.Value = num2str(size(app.RawImg,3)/10);
                app.Settings.RollingAvgWindow = app.WindowSizeEditField.Value;
            end
        end

        % Value changed function: AdjustHighSlider, AdjustLowSlider, 
        % ...and 2 other components
        function ImagecontrastadjustmentSwitchValueChanged(app, event)
%             value = app.ImagecontrastadjustmentSwitch.Value;
            adjusthigh = app.AdjustHighSlider.Value;
            adjustlow = app.AdjustLowSlider.Value;
            Gamma = app.GammaSlider.Value;
            
            app.AdjustedImg = ImageAdjust(app.RawImg,adjustlow,adjusthigh,Gamma);
            if app.ImageSharpeningSwitch.Value == "Off"
                app.FinalImg = app.AdjustedImg;
                ImageNumberSpinnerValueChanged(app,event)
            else
                ImageSharpeningSwitchValueChanged(app,event)
            end  
        end

        % Value changed function: AmountSlider, ImageSharpeningSwitch, 
        % ...and 2 other components
        function ImageSharpeningSwitchValueChanged(app, event)
%             value = app.ImageSharpeningSwitch.Value;
        Radius = app.RadiusSlider.Value;
        Amount = app.AmountSlider.Value;
        Threshold = app.ThresholdSlider.Value;
        
%         ImagecontrastadjustmentSwitchValueChanged(app,event)
        app.FinalImg = ImageSharpening(app.AdjustedImg,Radius,Amount,Threshold);

        ImageNumberSpinnerValueChanged(app,event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.StartTab = uitab(app.TabGroup);
            app.StartTab.Title = '1. Start';
            app.UIAxes = uiaxes(app.StartTab);
            title(app.UIAxes, 'Image')
            zlabel(app.UIAxes, 'Z')
            app.GetImageDirectoryButton = uibutton(app.StartTab, 'push');
            app.GetImageDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageDirectoryButtonPushed, true);
            app.GetImageDirectoryButton.Text = 'Get Image Directory';
            app.TextArea = uitextarea(app.StartTab);
            app.TrackButton = uibutton(app.StartTab, 'push');
            app.TrackButton.Text = 'Track';
            app.SavesettingsButton = uibutton(app.StartTab, 'push');
            app.SavesettingsButton.Text = 'Save settings';
            app.RollingAverageCheckBox = uicheckbox(app.StartTab);
            app.RollingAverageCheckBox.ValueChangedFcn = createCallbackFcn(app, @RollingAverageCheckBoxValueChanged, true);
            app.RollingAverageCheckBox.Text = 'Rolling Average';
            app.AssignImageSaveDirectoryButton = uibutton(app.StartTab, 'push');
            app.AssignImageSaveDirectoryButton.Text = 'Assign Image Save Directory';
            app.TextArea_2 = uitextarea(app.StartTab);
            app.Default1Label = uilabel(app.StartTab);
            app.Default1Label.Text = 'Default = 1';
            app.Default08Label = uilabel(app.StartTab);
            app.Default08Label.Text = 'Default = 0.8';
            app.Default0Label = uilabel(app.StartTab);
            app.Default0Label.Text = 'Default = 0';
            app.TextArea_3 = uitextarea(app.StartTab);
            app.TextArea_4 = uitextarea(app.StartTab);
            app.TextArea_5 = uitextarea(app.StartTab);
            app.TextArea_6 = uitextarea(app.StartTab);
            app.TextArea_7 = uitextarea(app.StartTab);
            app.BinaryThresholdSliderLabel = uilabel(app.StartTab);
            app.BinaryThresholdSliderLabel.Text = 'Binary Threshold';
            app.ImagecontrastadjustmentSwitch = uiswitch(app.StartTab, 'slider');
            app.ImagecontrastadjustmentSwitch.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.BinaryThresholdSlider = uislider(app.StartTab);
            app.BinaryThresholdSlider.Limits = [0 1];
            app.BinaryThresholdSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.BinaryThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @BinaryThresholdSliderValueChanged, true);
            app.BackgroundSubtractionSwitchLabel = uilabel(app.StartTab);
            app.BackgroundSubtractionSwitchLabel.Text = 'Background Subtraction';
            app.BackgroundSubtractionSwitch = uiswitch(app.StartTab, 'slider');
            app.BackgroundSubtractionSwitch.ValueChangedFcn = createCallbackFcn(app, @BackgroundSubtractionSwitchValueChanged, true);
            app.ImageSharpeningSwitchLabel = uilabel(app.StartTab);
            app.ImageSharpeningSwitchLabel.Text = 'Image Sharpening';
            app.ImagecontrastadjustmentSwitchLabel = uilabel(app.StartTab);
            app.ImagecontrastadjustmentSwitchLabel.Text = 'Image contrast adjustment';
            app.ImageSharpeningSwitch = uiswitch(app.StartTab, 'slider');
            app.ImageSharpeningSwitch.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.ImageNumberSpinnerLabel = uilabel(app.StartTab);
            app.ImageNumberSpinnerLabel.Text = 'Image Number';
            app.ImageNumberSpinner = uispinner(app.StartTab);
            app.ImageNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @ImageNumberSpinnerValueChanged, true);
            app.WindowSizeEditFieldLabel = uilabel(app.StartTab);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.StartTab, 'text');
            app.RadiusSliderLabel = uilabel(app.StartTab);
            app.RadiusSliderLabel.Text = 'Radius';
            app.RadiusSlider = uislider(app.StartTab);
            app.RadiusSlider.Limits = [0 5];
            app.RadiusSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.RadiusSlider.Value = 1;
            app.AmountSlider_3Label = uilabel(app.StartTab);
            app.AmountSlider_3Label.Text = 'Amount';
            app.AmountSlider = uislider(app.StartTab);
            app.AmountSlider.Limits = [0 5];
            app.AmountSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.AmountSlider.Value = 0.8;
            app.Label = uilabel(app.StartTab);
            app.Label.Text = 'This program is meant to only be used for a small number of images so that it can be run quickly';
            app.AdjustLowSliderLabel = uilabel(app.StartTab);
            app.AdjustLowSliderLabel.Text = 'Adjust Low';
            app.AdjustLowSlider = uislider(app.StartTab);
            app.AdjustLowSlider.Limits = [0 1];
            app.AdjustLowSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.AdjustHighSliderLabel = uilabel(app.StartTab);
            app.AdjustHighSliderLabel.Text = 'Adjust High';
            app.AdjustHighSlider = uislider(app.StartTab);
            app.AdjustHighSlider.Limits = [0 1];
            app.AdjustHighSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.AdjustHighSlider.Value = 1;
            app.CheckBinarizedCheckBox = uicheckbox(app.StartTab);
            app.CheckBinarizedCheckBox.Text = 'Check Binarized';
            app.GammaSliderLabel = uilabel(app.StartTab);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.StartTab);
            app.GammaSlider.Limits = [0 5];
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @ImagecontrastadjustmentSwitchValueChanged, true);
            app.GammaSlider.Value = 1;
            app.ThresholdSliderLabel = uilabel(app.StartTab);
            app.ThresholdSliderLabel.Text = 'Threshold';
            app.ThresholdSlider = uislider(app.StartTab);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ImageSharpeningSwitchValueChanged, true);
            app.ArealimEditFieldLabel = uilabel(app.StartTab);
            app.ArealimEditFieldLabel.Text = 'Arealim';
            app.ArealimEditField = uieditfield(app.StartTab, 'text');
            app.EditField = uieditfield(app.StartTab, 'text');
            app.MaxDisplacementEditFieldLabel = uilabel(app.StartTab);
            app.MaxDisplacementEditFieldLabel.Text = 'Max Displacement';
            app.MaxDisplacementEditField = uieditfield(app.StartTab, 'text');
            app.BackgroundTab = uitab(app.TabGroup);
            app.BackgroundTab.Title = 'Background';
            app.ContrastTab = uitab(app.TabGroup);
            app.ContrastTab.Title = 'Contrast';
            app.SharpenTab = uitab(app.TabGroup);
            app.SharpenTab.Title = 'Sharpen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIParticleTracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end